classdef bh_7dof_car_content_navigator_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        bh_7dof_car_content_navigator_appUIFigure  matlab.ui.Figure
        dofCarDynamicsTRANSVERSECaseStudyLabel  matlab.ui.control.Label
        TabGroup                      matlab.ui.container.TabGroup
        WELCOMETab                    matlab.ui.container.Tab
        Image                         matlab.ui.control.Image
        TextArea_2                    matlab.ui.control.TextArea
        WelcomeLabel                  matlab.ui.control.Label
        ROADTab                       matlab.ui.container.Tab
        TextArea_3                    matlab.ui.control.TextArea
        Image2                        matlab.ui.control.Image
        Knob_ROAD                     matlab.ui.control.DiscreteKnob
        CARTab                        matlab.ui.container.Tab
        Image3                        matlab.ui.control.Image
        Knob_7DOFCAR                  matlab.ui.control.DiscreteKnob
        TextArea_4                    matlab.ui.control.TextArea
        DATATab                       matlab.ui.container.Tab
        CARDATABASEFILEPanel          matlab.ui.container.Panel
        OpenEXCELfileforastickybeakButton  matlab.ui.control.Button
        TextArea_5                    matlab.ui.control.TextArea
        ACTIVE_SUSPTab                matlab.ui.container.Tab
        Active_Susp_Knob              matlab.ui.control.DiscreteKnob
        SYSTEM_CHECKTab               matlab.ui.container.Tab
        PRODUCTSPanel                 matlab.ui.container.Panel
        thistestmaytakeafewmomentsLabel  matlab.ui.control.Label
        LAMP_C_Comp_C                 matlab.ui.control.Lamp
        LAMP_C_Comp_B                 matlab.ui.control.Lamp
        LAMP_C_Comp_A                 matlab.ui.control.Lamp
        CCompilerLabel                matlab.ui.control.Label
        Hyperlink                     matlab.ui.control.Hyperlink
        AsupportedCcompileralsoneedstobeinstalledonyourlaptopLabel  matlab.ui.control.Label
        LAMP_ML                       matlab.ui.control.Lamp
        MATLABLampLabel               matlab.ui.control.Label
        LAMP_SYMT                     matlab.ui.control.Lamp
        SymbolicMathToolboxLampLabel  matlab.ui.control.Label
        LAMP_SL                       matlab.ui.control.Lamp
        SimulinkLabel                 matlab.ui.control.Label
        LAMP_SC                       matlab.ui.control.Lamp
        SimscapeLabel                 matlab.ui.control.Label
        LAMP_SCMB                     matlab.ui.control.Lamp
        SimscapeMultibodyLabel        matlab.ui.control.Label
        SYS_CHECK_But                 matlab.ui.control.Button
        TextArea                      matlab.ui.control.TextArea
        DoyouhavetherequiredproductsneededforthisDEMOLabel  matlab.ui.control.Label
        TIPSFYITab                    matlab.ui.container.Tab
        Panel                         matlab.ui.container.Panel
        TabGroup2                     matlab.ui.container.TabGroup
        ModellingTab                  matlab.ui.container.Tab
        TIPS_FYI_HELPButton           matlab.ui.control.Button
        TextArea_6                    matlab.ui.control.TextArea
        TIPS_FYI_DropDown             matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        OBJ_select % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           app.OBJ_select =  bh_7dof_car_demo_selector_CLS();
        end

        % Button pushed function: SYS_CHECK_But
        function SYS_CHECK_ButButtonPushed(app, event)
            app.OBJ_select.do_system_check(app);
        end

        % Value changed function: Knob_ROAD
        function Knob_ROADValueChanged(app, event)
            value = app.Knob_ROAD.Value;

            switch(upper(value))
                case {'NOTHING'}
                        % do NOTHING
                case {upper('Explore Speed Bump Road')}
                        app.OBJ_select.open_ROAD_demos("ROAD_HUMPS_EXPLORE");                
                otherwise                  
                    error('###_ERROR: UNknown ROAD choice !');
            end                                   
            
        end

        % Value changed function: Knob_7DOFCAR
        function Knob_7DOFCARValueChanged(app, event)
            value = app.Knob_7DOFCAR.Value;

            switch(upper(value))
                case {'NOTHING'}
                        % do NOTHING
                case {upper('Derive EOMs')}
                        app.OBJ_select.open_7DOFCAR_demos("DERIVE_EOMS");      
                case {upper('Brain model')}
                        app.OBJ_select.open_7DOFCAR_demos("BRAIN_MODEL");                          
                case {upper('Simscape model')}                    
                        app.OBJ_select.open_7DOFCAR_demos("SIMSCAPE_MODEL");                             
                case {upper('Simulate at MODAL freqs')}
                        app.OBJ_select.open_7DOFCAR_demos("SIMULATE_AT_MODAL");                  
                case {upper('Simulate with Road Profile')}
                        app.OBJ_select.open_7DOFCAR_demos("SIMULATE_ROAD_HUMPS");     
                case {upper('Validate Brain and Simscape')}
                        app.OBJ_select.open_7DOFCAR_demos("SIMULATE_VALIDATE");
                otherwise                  
                    error('###_ERROR: UNknown 7DOFCAR choice !');
            end                                               
            
        end

        % Button pushed function: OpenEXCELfileforastickybeakButton
        function OpenEXCELfileforastickybeakButtonPushed(app, event)
            app.OBJ_select.open_DATA_demos("EXCEL_DATABASE_FILE"); 
        end

        % Button pushed function: TIPS_FYI_HELPButton
        function TIPS_FYI_HELPButtonPushed(app, event)
            value = app.TIPS_FYI_DropDown.Value;
            
            switch(upper(value))
                case {upper('Car models - Subsystem Reference')}
                        app.OBJ_select.open_tips_fyi_topic("SUBSYSTEM_REFERENCE_01");      
                otherwise                  
                    error('###_ERROR: UNknown TIPS/FYI choice !');
            end                               
            
        end

        % Value changed function: Active_Susp_Knob
        function Active_Susp_KnobValueChanged(app, event)
           
            value = app.Active_Susp_Knob.Value;
                     
             switch(upper(value))
                case {'NOTHING'}
                        % do NOTHING
                case {upper('DESIGN')}
                         app.OBJ_select.open_active_suspension_demos("DESIGN");
                otherwise                  
                    error('###_ERROR: UNknown 7DOFCAR choice !');
            end                                                  
                        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.bh_7dof_car_content_navigator_appUIFigure = uifigure('Visible', 'off');
            app.bh_7dof_car_content_navigator_appUIFigure.Name = 'bh_7dof_car_content_navigator_app';
            app.TabGroup = uitabgroup(app.bh_7dof_car_content_navigator_appUIFigure);
            app.WELCOMETab = uitab(app.TabGroup);
            app.WELCOMETab.Title = 'WELCOME';
            app.WelcomeLabel = uilabel(app.WELCOMETab);
            app.WelcomeLabel.Text = 'Welcome ';
            app.TextArea_2 = uitextarea(app.WELCOMETab);
            app.TextArea_2.Value = {'Let''s explore the TRANSVERSE dynamics of a 7-dof car model.  We''ll'; ''; '1.)  Derive the equations of motion'; '2.)  Perform a MODAL Analysis of the system'; '3.)  Simulate the car model for excitations at the MODAL frequencies'; '4.)  Develop a Road Profile model that represents speed humps'; '5.)  Simulate the car model driving over our Road Profile'};
            app.Image = uiimage(app.WELCOMETab);
            app.Image.ImageSource = 'bh_PIC_01.PNG';
            app.ROADTab = uitab(app.TabGroup);
            app.ROADTab.Title = 'ROAD';
            app.Knob_ROAD = uiknob(app.ROADTab, 'discrete');
            app.Knob_ROAD.Items = {'NOTHING', 'Explore Speed Bump Road'};
            app.Knob_ROAD.ValueChangedFcn = createCallbackFcn(app, @Knob_ROADValueChanged, true);
            app.Knob_ROAD.Value = 'NOTHING';
            app.Image2 = uiimage(app.ROADTab);
            app.Image2.ImageSource = 'bh_PIC_02.PNG';
            app.TextArea_3 = uitextarea(app.ROADTab);
            app.TextArea_3.Value = {'Pick a Road Profile model to explore'};
            app.CARTab = uitab(app.TabGroup);
            app.CARTab.Title = 'CAR';
            app.TextArea_4 = uitextarea(app.CARTab);
            app.TextArea_4.Value = {'Pick an analysis task to perform with the7-dof car model'};
            app.Knob_7DOFCAR = uiknob(app.CARTab, 'discrete');
            app.Knob_7DOFCAR.Items = {'NOTHING', 'Derive EOMs', 'Brain model', 'Simscape model', 'Simulate at MODAL freqs', 'Simulate with Road Profile', 'Validate Brain and Simscape'};
            app.Knob_7DOFCAR.ValueChangedFcn = createCallbackFcn(app, @Knob_7DOFCARValueChanged, true);
            app.Knob_7DOFCAR.Value = 'NOTHING';
            app.Image3 = uiimage(app.CARTab);
            app.Image3.ImageSource = 'bh_PIC_03.PNG';
            app.DATATab = uitab(app.TabGroup);
            app.DATATab.Title = 'DATA';
            app.CARDATABASEFILEPanel = uipanel(app.DATATab);
            app.CARDATABASEFILEPanel.Title = 'CAR DATABASE FILE';
            app.TextArea_5 = uitextarea(app.CARDATABASEFILEPanel);
            app.TextArea_5.Value = {'IFF you are using WINDOWS ... AND you have Microsoft EXCEL installed,'; 'then you can open the EXCEL file that contains the various car configurations used in this DEMO.'; ''};
            app.OpenEXCELfileforastickybeakButton = uibutton(app.CARDATABASEFILEPanel, 'push');
            app.OpenEXCELfileforastickybeakButton.ButtonPushedFcn = createCallbackFcn(app, @OpenEXCELfileforastickybeakButtonPushed, true);
            app.OpenEXCELfileforastickybeakButton.Text = 'Open EXCEL file for a stickybeak';
            app.ACTIVE_SUSPTab = uitab(app.TabGroup);
            app.ACTIVE_SUSPTab.Title = 'ACTIVE_SUSP';
            app.Active_Susp_Knob = uiknob(app.ACTIVE_SUSPTab, 'discrete');
            app.Active_Susp_Knob.Items = {'NOTHING', 'DESIGN'};
            app.Active_Susp_Knob.ValueChangedFcn = createCallbackFcn(app, @Active_Susp_KnobValueChanged, true);
            app.Active_Susp_Knob.Value = 'NOTHING';
            app.SYSTEM_CHECKTab = uitab(app.TabGroup);
            app.SYSTEM_CHECKTab.Title = 'SYSTEM_CHECK';
            app.PRODUCTSPanel = uipanel(app.SYSTEM_CHECKTab);
            app.PRODUCTSPanel.Title = 'PRODUCTS';
            app.DoyouhavetherequiredproductsneededforthisDEMOLabel = uilabel(app.PRODUCTSPanel);
            app.DoyouhavetherequiredproductsneededforthisDEMOLabel.Text = 'Do you have the required products needed for this DEMO?';
            app.TextArea = uitextarea(app.PRODUCTSPanel);
            app.TextArea.Value = {'If you see any RED lamps, you may not be able to run some of the demos.'};
            app.SYS_CHECK_But = uibutton(app.PRODUCTSPanel, 'push');
            app.SYS_CHECK_But.ButtonPushedFcn = createCallbackFcn(app, @SYS_CHECK_ButButtonPushed, true);
            app.SYS_CHECK_But.Text = 'CLICK ME to test your installation';
            app.SimscapeMultibodyLabel = uilabel(app.PRODUCTSPanel);
            app.SimscapeMultibodyLabel.Text = 'Simscape Multibody';
            app.LAMP_SCMB = uilamp(app.PRODUCTSPanel);
            app.SimscapeLabel = uilabel(app.PRODUCTSPanel);
            app.SimscapeLabel.Text = 'Simscape';
            app.LAMP_SC = uilamp(app.PRODUCTSPanel);
            app.SimulinkLabel = uilabel(app.PRODUCTSPanel);
            app.SimulinkLabel.Text = 'Simulink';
            app.LAMP_SL = uilamp(app.PRODUCTSPanel);
            app.SymbolicMathToolboxLampLabel = uilabel(app.PRODUCTSPanel);
            app.SymbolicMathToolboxLampLabel.Text = 'Symbolic Math Toolbox';
            app.LAMP_SYMT = uilamp(app.PRODUCTSPanel);
            app.MATLABLampLabel = uilabel(app.PRODUCTSPanel);
            app.MATLABLampLabel.Text = 'MATLAB';
            app.LAMP_ML = uilamp(app.PRODUCTSPanel);
            app.AsupportedCcompileralsoneedstobeinstalledonyourlaptopLabel = uilabel(app.PRODUCTSPanel);
            app.AsupportedCcompileralsoneedstobeinstalledonyourlaptopLabel.Text = 'A supported C-compiler also needs to be installed on your laptop:';
            app.Hyperlink = uihyperlink(app.PRODUCTSPanel);
            app.Hyperlink.URL = 'https://www.mathworks.com/support/requirements/supported-compilers.html';
            app.Hyperlink.Text = 'Supported C-compilers for MATLAB and Simulink';
            app.CCompilerLabel = uilabel(app.PRODUCTSPanel);
            app.CCompilerLabel.Text = 'C-Compiler';
            app.LAMP_C_Comp_A = uilamp(app.PRODUCTSPanel);
            app.LAMP_C_Comp_B = uilamp(app.PRODUCTSPanel);
            app.LAMP_C_Comp_C = uilamp(app.PRODUCTSPanel);
            app.thistestmaytakeafewmomentsLabel = uilabel(app.PRODUCTSPanel);
            app.thistestmaytakeafewmomentsLabel.Text = '( this test may take a few moments ! )';
            app.TIPSFYITab = uitab(app.TabGroup);
            app.TIPSFYITab.Title = 'TIPS/FYI';
            app.Panel = uipanel(app.TIPSFYITab);
            app.Panel.Title = 'Panel';
            app.TabGroup2 = uitabgroup(app.Panel);
            app.ModellingTab = uitab(app.TabGroup2);
            app.ModellingTab.Title = 'Modelling';
            app.TIPS_FYI_DropDown = uidropdown(app.ModellingTab);
            app.TIPS_FYI_DropDown.Items = {'Car models - Subsystem Reference'};
            app.TIPS_FYI_DropDown.Value = 'Car models - Subsystem Reference';
            app.TextArea_6 = uitextarea(app.ModellingTab);
            app.TextArea_6.Value = {'Pick a topic and click HELP'; ''};
            app.TIPS_FYI_HELPButton = uibutton(app.ModellingTab, 'push');
            app.TIPS_FYI_HELPButton.ButtonPushedFcn = createCallbackFcn(app, @TIPS_FYI_HELPButtonPushed, true);
            app.TIPS_FYI_HELPButton.Text = 'HELP';
            app.dofCarDynamicsTRANSVERSECaseStudyLabel = uilabel(app.bh_7dof_car_content_navigator_appUIFigure);
            app.dofCarDynamicsTRANSVERSECaseStudyLabel.Text = '7-dof Car Dynamics (TRANSVERSE) Case Study';
            % Show the figure after all components are created
            app.bh_7dof_car_content_navigator_appUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = bh_7dof_car_content_navigator_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.bh_7dof_car_content_navigator_appUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.bh_7dof_car_content_navigator_appUIFigure)
        end
    end
end